classdef Autograder < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        AutograderUIFigure           matlab.ui.Figure
        FileMenu                     matlab.ui.container.Menu
        SaveConfiguration            matlab.ui.container.Menu
        LoadConfigurationMenu        matlab.ui.container.Menu
        Exit                         matlab.ui.container.Menu
        AcknowledgementsMenu         matlab.ui.container.Menu
        LicenseMenu                  matlab.ui.container.Menu
        DocumentationMenu            matlab.ui.container.Menu
        HomeworkSubmissionPanel      matlab.ui.container.Panel
        IsResubmission               matlab.ui.control.CheckBox
        HomeworkNumberLabel          matlab.ui.control.Label
        HomeworkNumber               matlab.ui.control.NumericEditField
        HomeworkChoiceLabel          matlab.ui.control.Label
        HomeworkChoice               matlab.ui.control.DropDown
        CanvasTokenForHomeworkLabel  matlab.ui.control.Label
        CanvasTokenForHomework       matlab.ui.control.EditField
        HomeworkBrowser              matlab.ui.control.Button
        CanvasBrowser                matlab.ui.control.Button
        CanvasTokenGenerator         matlab.ui.control.Button
        SolutionArchivePanel         matlab.ui.container.Panel
        SolutionChoiceLabel          matlab.ui.control.Label
        SolutionChoice               matlab.ui.control.DropDown
        SubmissionBrowser            matlab.ui.control.Button
        DriveTokenLabel              matlab.ui.control.Label
        DriveToken                   matlab.ui.control.EditField
        DriveBrowser                 matlab.ui.control.Button
        DriveTokenGenerator          matlab.ui.control.Button
        OutputsPanel                 matlab.ui.container.Panel
        UploadToCanvas               matlab.ui.control.CheckBox
        UploadToServer               matlab.ui.control.CheckBox
        StoreLocally                 matlab.ui.control.CheckBox
        StoreDebugger                matlab.ui.control.CheckBox
        PostAnnouncement             matlab.ui.control.CheckBox
        SendEmail                    matlab.ui.control.CheckBox
        CanvasTokenForUploadLabel    matlab.ui.control.Label
        CanvasTokenForUpload         matlab.ui.control.EditField
        ServerUsernameLabel          matlab.ui.control.Label
        ServerUsername               matlab.ui.control.EditField
        ServerPasswordLabel          matlab.ui.control.Label
        ServerPassword               matlab.ui.control.EditField
        EmailAddressLabel            matlab.ui.control.Label
        EmailAddress                 matlab.ui.control.EditField
        CanvasTokenForAnnouncementLabel  matlab.ui.control.Label
        CanvasTokenForAnnouncement   matlab.ui.control.EditField
        OutputBrowser                matlab.ui.control.Button
        DebuggerBrowser              matlab.ui.control.Button
        GoButton                     matlab.ui.control.Button
        CancelButton                 matlab.ui.control.Button
    end


    properties (Access = public)
        % Credentials
        canvasToken char
        driveToken char
        serverUsername char
        serverPassword char
        
        % Homework Information
        submissionArchivePath char
        canvasCourseId char
        canvasHomeworkId char
        homeworkNum double
        isResubmission logical
        
        % Solution Information
        solutionArchivePath char
        driveFolderId char
        
        % Options
        localOutputPath char
        localDebugPath char
        homeworkName char
        email char
    end

    methods (Access = private)
        function results = getZip(~, prompt)
            [name, path] = uigetfile({'*.zip', 'ZIP Archives (*.zip)'}, prompt);
            if isequal(name, 0)
                results = false;
            else
                results = [path name];
            end
        end

        function results = getFolder(~, prompt)
            path = uigetdir(userpath(), prompt);
            if isequal(path, 0)
                results = false;
            else
                results = path;
            end
        end
        
    end

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % hide optionals
            app.CanvasTokenForHomework.Visible = false;
            app.CanvasTokenForHomeworkLabel.Visible = false;
            app.CanvasBrowser.Visible = false;
            app.CanvasTokenForAnnouncement.Visible = false;
            app.CanvasTokenForAnnouncementLabel.Visible = false;
            app.CanvasTokenForUpload.Visible = false;
            app.CanvasTokenForUploadLabel.Visible = false;
            
            app.HomeworkBrowser.Visible = false;
            app.SubmissionBrowser.Visible = false;
            
            app.EmailAddress.Visible = false;
            app.EmailAddressLabel.Visible = false;
            
            app.ServerUsername.Visible = false;
            app.ServerUsernameLabel.Visible = false;
            app.ServerPassword.Visible = false;
            app.ServerPasswordLabel.Visible = false;
            
            app.DriveBrowser.Visible = false;
            app.DriveBrowser.Enable = false;
            app.DriveTokenGenerator.Visible = false;
            app.DriveToken.Visible = false;
            app.DriveTokenLabel.Visible = false;
            
            app.OutputBrowser.Visible = false;
            app.DebuggerBrowser.Visible = false;
            
            app.HomeworkChoice.ItemsData = [0 1 2];
            app.SolutionChoice.ItemsData = [0 1 2];
        end

        % Value changed function: HomeworkChoice
        function HomeworkChoiceSelected(app, event)
            persistent isFirst;
            if isempty(isFirst)
                isFirst = true;
            end
            value = app.HomeworkChoice.Value;
            if value == 1
                app.CanvasTokenForHomework.Visible = true;
                app.CanvasTokenForHomeworkLabel.Visible = true;
                app.CanvasBrowser.Visible = true;
                app.CanvasTokenGenerator.Visible = true;
                % move the number, isResub down by height of this
                app.IsResubmission.Position = app.IsResubmission.Position - ...
                    [0 app.CanvasTokenForHomework.Position(4) 0 0];
                app.HomeworkNumber.Position = app.HomeworkNumber.Position - ...
                    [0 app.CanvasTokenForHomework.Position(4) 0 0];
                app.HomeworkNumberLabel.Position = app.HomeworkNumberLabel.Position - ...
                    [0 app.CanvasTokenForHomework.Position(4) 0 0];
                app.HomeworkBrowser.Visible = false;
                isFirst = false;
            else
                % check if first; we don't move up if it is
                if ~isFirst
                    % hide it     
                    app.CanvasTokenForHomework.Visible = false;
                    app.CanvasTokenForHomeworkLabel.Visible = false;
                    app.CanvasBrowser.Visible = false;
                    app.CanvasTokenGenerator.Visible = false;
                    % move back up
                    app.IsResubmission.Position = app.IsResubmission.Position + ...
                        [0 app.CanvasTokenForHomework.Position(4) 0 0];
                    app.HomeworkNumber.Position = app.HomeworkNumber.Position + ...
                        [0 app.CanvasTokenForHomework.Position(4) 0 0];
                    app.HomeworkNumberLabel.Position = app.HomeworkNumberLabel.Position + ...
                        [0 app.CanvasTokenForHomework.Position(4) 0 0];
                end
                if value == 2
                    app.HomeworkBrowser.Visible = true;
                    isFirst = true;
                else
                    app.HomeworkBrowser.Visible = false;
                    isFirst = true;
                end
            end
            if value == 1
                % hide zip browser
                app.HomeworkBrowser.Visible = false;
                % check if we have canvasToken, if we do, go straight to CanvasHomeworkSelector.
                % otherwise, authorize first
                if isempty(app.canvasToken)
                    % authorize first
                    auth = CanvasAuthorizer(app);
                    uiwait(auth.UIFigure);
                    delete(auth);
                end
                if isempty(app.canvasToken)
                    app.HomeworkChoice.Value = 0;
                    app.HomeworkChoiceSelected();
                    return;
                else
                    browser = CanvasHomeworkSelector(app);
                    uiwait(browser.UIFigure);
                    delete(browser);
                    % if no answer given (cancelled), then revert to 0
                    if isempty(app.canvasHomeworkId)
                        app.HomeworkChoice.Value = 0;
                        app.HomeworkChoiceSelected();
                        return;
                    end
                    app.CanvasBrowser.Visible = true;
                    app.CanvasTokenGenerator.Visible = true;
                end
            elseif value == 2
                % get zip
                res = app.getZip('Select the HW Zipped Archive');
                if ~islogical(res)
                    app.submissionArchivePath = res;
                    app.SubmissionBrowser.Visible = true;
                    app.CanvasBrowser.Visible = false;
                    app.CanvasTokenGenerator.Visible = false;
                else
                    app.HomeworkChoice.Value = 0;
                    app.HomeworkChoiceSelected();
                    return;
                end
            else
                % hide everything
                app.HomeworkBrowser.Visible = false;
                app.CanvasBrowser.Visible = false;
                app.CanvasTokenGenerator.Visible = false;
            end
        end

        % Button pushed function: HomeworkBrowser
        function HomeworkBrowserButtonPushed(app, event)
            res = app.getZip('Select your Homework ZIP');
            if ~islogical(res)
                app.submissionArchivePath = res;
            end
        end

        % Button pushed function: SubmissionBrowser
        function SubmissionBrowserPushed(app, event)
            res = app.getZip('Select the solution archive');
            if ~islogical(res)
                app.solutionArchivePath = res;
            end
        end

        % Value changed function: UploadToCanvas
        function UploadToCanvasValueChanged(app, event)
            value = app.UploadToCanvas.Value;
            if value
                app.CanvasTokenForUpload.Visible = true;
                app.CanvasTokenForUploadLabel.Visible = true;
                if ~isempty(app.canvasToken)
                    app.CanvasTokenForUpload.Value = app.canvasToken;
                end
            else
                app.CanvasTokenForUpload.Visible = false;
                app.CanvasTokenForUploadLabel.Visible = false;
            end
        end

        % Value changed function: UploadToServer
        function UploadToServerValueChanged(app, event)
            value = app.UploadToServer.Value;
            if value
                app.ServerPassword.Visible = true;
                app.ServerPasswordLabel.Visible = true;
                app.ServerUsername.Visible = true;
                app.ServerUsernameLabel.Visible = true;
            else
                app.ServerPassword.Visible = false;
                app.ServerPasswordLabel.Visible = false;
                app.ServerUsername.Visible = false;
                app.ServerUsernameLabel.Visible = false;
            end
        end

        % Value changed function: StoreLocally
        function StoreLocallyValueChanged(app, event)
            value = app.StoreLocally.Value;
            if value
                res = app.getFolder('Where should we store the output?');
                if ~islogical(res)
                    app.localOutputPath = res;
                    app.OutputBrowser.Visible = true;
                else
                    app.StoreLocally.Value = false;
                    app.localOutputPath = '';
                    app.OutputBrowser.Visible = false;
                end
            else
                app.localOutputPath = '';
                app.OutputBrowser.Visible = false;
            end
        end

        % Button pushed function: OutputBrowser
        function OutputBrowserButtonPushed(app, event)
            res = app.getFolder('Where should we store the output?');
            if ~islogical(res)
                app.localOutputPath = res;
            end
        end

        % Value changed function: StoreDebugger
        function StoreDebuggerValueChanged(app, event)
            value = app.StoreDebugger.Value;
            if value
                res = app.getFolder('Where should we store the intermediate information?');
                if ~islogical(res)
                    app.localDebugPath = res;
                    app.DebuggerBrowser.Visible = true;
                else
                    app.StoreDebugger.Value = false;
                    app.localDebugPath = '';
                    app.DebuggerBrowser.Visible = false;
                end
            else
                app.localDebugPath = '';
                app.DebuggerBrowser.Visible = false;
            end
            
        end

        % Button pushed function: DebuggerBrowser
        function DebuggerBrowserButtonPushed(app, event)
            res = app.getFolder('Where should we store the intermediate information?');
            if ~islogical(res)
                app.localDebugPath = res;
            end
        end

        % Value changed function: SolutionChoice
        function SolutionChoiceValueChanged(app, event)
            value = app.SolutionChoice.Value;
            if value == 1
                app.DriveBrowser.Visible = true;
                app.DriveToken.Visible = true;
                app.DriveTokenLabel.Visible = true;
                app.DriveTokenGenerator.Visible = true;
                app.SubmissionBrowser.Visible = false;
            elseif value == 2
                app.SubmissionBrowser.Visible = true;
                app.DriveBrowser.Visible = false;
                app.DriveToken.Visible = false;
                app.DriveTokenLabel.Visible = false;
                app.DriveTokenGenerator.Visible = false;
            else
                app.SubmissionBrowser.Visible = false;
                app.DriveBrowser.Visible = false;
                app.DriveToken.Visible = false;
                app.DriveTokenLabel.Visible = false;
                app.DriveTokenGenerator.Visible = false;
            end
        end

        % Callback function: CancelButton, Exit
        function CancelButtonPushed(app, event)
            delete(app);
        end

        % Value changed function: PostAnnouncement
        function PostAnnouncementValueChanged(app, event)
            value = app.PostAnnouncement.Value;
            if value
                app.CanvasTokenForAnnouncement.Visible = true;
                app.CanvasTokenForAnnouncementLabel.Visible = true;
                if ~isempty(app.canvasToken)
                    app.CanvasTokenForAnnouncement.Value = app.canvasToken;
                end
            else
                app.CanvasTokenForAnnouncement.Visible = false;
                app.CanvasTokenForAnnouncementLabel.Visible = false;
            end
        end

        % Value changed function: SendEmail
        function SendEmailValueChanged(app, event)
            value = app.SendEmail.Value;
            if value
                app.EmailAddress.Visible = true;
                app.EmailAddressLabel.Visible = true;
            else
                app.EmailAddress.Visible = false;
                app.EmailAddressLabel.Visible = false;
            end
        end

        % Button pushed function: CanvasBrowser
        function CanvasBrowserButtonPushed(app, event)
            app.canvasToken = app.CanvasTokenForHomework.Value;
            canvasSelector = CanvasHomeworkSelector(app);
            uiwait(canvasSelector.UIFigure);
            delete(canvasSelector);
            app.HomeworkNumber.Value = app.homeworkNum;
            app.IsResubmission.Value = app.isResubmission;
        end

        % Button pushed function: DriveTokenGenerator
        function DriveTokenGeneratorButtonPushed(app, event)
            generator = GoogleDriveAuthorizer(app);
            uiwait(generator.UIFigure);
        end

        % Button pushed function: DriveBrowser
        function DriveBrowserButtonPushed(app, event)
            app.driveToken = app.DriveToken.Value;
            browser = GoogleDriveBrowser(app);
            uiwait(browser.UIFigure);
        end

        % Value changing function: DriveToken
        function DriveTokenValueChanging(app, event)
            changingValue = event.Value;
            if isempty(changingValue)
                app.DriveBrowser.Enable = false;
            else
                app.DriveBrowser.Enable = true;
            end
        end

        % Menu selected function: SaveConfiguration
        function SaveConfigurationMenuSelected(app, event)
            % config file is text file with field: value on each line
            % fields are:
            %
            % * canvasToken
            % * driveToken
            % * serverUsername
            % * serverPassword
            % * emailAddress
            % * downloadFromCanvas
            % * downloadFromDrive
            % * uploadToCanvas
            % * uploadToServer
            % * announceToCanvas
            % * sendEmail
            %
            % values are all strings. booleans are encoded as 1 or 0
            
            prefs = struct('canvasToken', app.canvasToken, ...
                'driveToken', app.driveToken, ...
                'serverUsername', app.serverUsername, ...
                'serverPassword', app.serverPassword, ...
                'emailAddress', app.email, ...
                'downloadFromCanvas', '', ...
                'downloadFromDrive', '', ...
                'uploadToCanvas', app.UploadToCanvas.Value, ...
                'uploadToServer', app.UploadToServer.Value, ...
                'announceToCanvas', app.PostAnnouncement.Value, ...
                'sendEmail', app.SendEmail.Value);
            if app.HomeworkChoice.Value == 1
                prefs.downloadFromCanvas = true;
            else
                prefs.downloadFromCanvas = false;
            end
            if app.SolutionChoice.Value == 1
                prefs.downloadFromDrive = true;
            else
                prefs.downloadFromDrive = false;
            end
            
            % ask where to save
            [name, path] = uiputfile({'*.autograde', 'Autograder Configuration Files (*.autograde)'}, 'Where shall we save your configuration file?');
            if ~isequal(name, 0)
                fid = fopen([path name], 'wt');
                fields = fieldnames(prefs);
                for f = 1:numel(fields)
                    if islogical(prefs.(fields{f}))
                        prefs.(fields{f}) = num2str(prefs.(fields{f}));
                    end
                    fprintf(fid, '%s:%s\n', fields{f}, prefs.(fields{f}));
                end
                fclose(fid);
            end
        end

        % Menu selected function: LoadConfigurationMenu
        function LoadConfigurationMenuSelected(app, event)
            % ask for file; if given, load data
            [name, path] = uigetfile({'*.autograde', 'Autograder Configuration Files (*.autograde)'}, 'Select the Autograder Configuration File...');
            if ~isequal(name, 0)
                fid = fopen([path name], 'rt');
                lines = strsplit(char(fread(fid))', newline);
                fclose(fid);
                % for each line, see what field it is
                prefs = struct('canvasToken', app.canvasToken, ...
                'driveToken', app.driveToken, ...
                'serverUsername', app.serverUsername, ...
                'serverPassword', app.serverPassword, ...
                'emailAddress', app.email, ...
                'downloadFromCanvas', '', ...
                'downloadFromDrive', '', ...
                'uploadToCanvas', app.UploadToCanvas.Value, ...
                'uploadToServer', app.UploadToServer.Value, ...
                'announceToCanvas', app.PostAnnouncement.Value);
                for l = 1:numel(lines)
                    line = lines{l};
                    [field, val] = strtok(line, ':');
                    % if val is empty (just :), make ''
                    if length(val) == 1
                        val = '';
                    elseif length(val) == 2 && val(2) == '1'
                        val = true;
                    elseif length(val) == 2 && val(2) == '0'
                        val = false;
                    end
                    switch lower(field)
                        case 'canvastoken'
                            app.canvasToken = val;
                        case 'drivetoken'
                            app.driveToken = val;
                        case 'serverusername'
                            app.serverUsername = val;
                        case 'serverpassword'
                            app.serverPassword = val;
                        case 'emailaddress'
                            app.email = val;
                        case 'downloadfromcanvas'
                            if val
                                app.HomeworkChoice.Value = 1;
                                app.HomeworkChoiceSelected([]);
                            end  
                        case 'downloadfromdrive'
                            if val
                                app.SolutionChoice.Value = 1;
                                app.SolutionChoiceValueChanged([]);
                            end
                        case 'uploadtocanvas'
                            app.UploadToCanvas.Value = val;
                            app.UploadToCanvasValueChanged([]);
                        case 'uploadtoserver'
                            app.UploadToServer.Value = val;
                            app.UploadToServerValueChanged([]);
                        case 'announcetocanvas'
                            app.PostAnnouncement.Value = val;
                            app.PostAnnouncementValueChanged([]);
                        case 'sendemail'
                            app.SendEmail.Value = val;
                            app.EmailAddress.Value = app.email;
                    end
                end
            end
        end

        % Menu selected function: LicenseMenu
        function LicenseMenuSelected(app, event)
            web('https://github.gatech.edu/CS1371/autograder/blob/Development/LICENSE', '-browser');
        end

        % Menu selected function: DocumentationMenu
        function DocumentationMenuSelected(app, event)
            web('https://github.gatech.edu/pages/CS1371/autograder/', '-browser');
        end

        % Button pushed function: CanvasTokenGenerator
        function CanvasTokenGeneratorButtonPushed(app, event)
            generator = CanvasAuthorizer(app);
            uiwait(generator.UIFigure);
            delete(generator);
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.AutograderUIFigure = uifigure;
            app.AutograderUIFigure.Name = 'Autograder';
            app.FileMenu = uimenu(app.AutograderUIFigure);
            app.FileMenu.Text = 'File';
            app.SaveConfiguration = uimenu(app.FileMenu);
            app.SaveConfiguration.MenuSelectedFcn = createCallbackFcn(app, @SaveConfigurationMenuSelected, true);
            app.SaveConfiguration.Text = 'Save Configuration...';
            app.LoadConfigurationMenu = uimenu(app.FileMenu);
            app.LoadConfigurationMenu.MenuSelectedFcn = createCallbackFcn(app, @LoadConfigurationMenuSelected, true);
            app.LoadConfigurationMenu.Text = 'Load Configuration...';
            app.Exit = uimenu(app.FileMenu);
            app.Exit.MenuSelectedFcn = createCallbackFcn(app, @CancelButtonPushed, true);
            app.Exit.Text = 'Exit';
            app.AcknowledgementsMenu = uimenu(app.AutograderUIFigure);
            app.AcknowledgementsMenu.Text = 'Acknowledgements';
            app.LicenseMenu = uimenu(app.AcknowledgementsMenu);
            app.LicenseMenu.MenuSelectedFcn = createCallbackFcn(app, @LicenseMenuSelected, true);
            app.LicenseMenu.Text = 'License';
            app.DocumentationMenu = uimenu(app.AcknowledgementsMenu);
            app.DocumentationMenu.MenuSelectedFcn = createCallbackFcn(app, @DocumentationMenuSelected, true);
            app.DocumentationMenu.Text = 'Documentation';
            app.HomeworkSubmissionPanel = uipanel(app.AutograderUIFigure);
            app.HomeworkSubmissionPanel.Title = '1. Homework Submission';
            app.IsResubmission = uicheckbox(app.HomeworkSubmissionPanel);
            app.IsResubmission.Text = 'Resubmission';
            app.HomeworkNumberLabel = uilabel(app.HomeworkSubmissionPanel);
            app.HomeworkNumberLabel.Text = 'Homework Number';
            app.HomeworkNumber = uieditfield(app.HomeworkSubmissionPanel, 'numeric');
            app.HomeworkChoiceLabel = uilabel(app.HomeworkSubmissionPanel);
            app.HomeworkChoiceLabel.Text = 'I would like to...';
            app.HomeworkChoice = uidropdown(app.HomeworkSubmissionPanel);
            app.HomeworkChoice.Items = {'', 'Select the submission from Canvas', 'Browse locally for a submission archive'};
            app.HomeworkChoice.ValueChangedFcn = createCallbackFcn(app, @HomeworkChoiceSelected, true);
            app.HomeworkChoice.Value = '';
            app.CanvasTokenForHomeworkLabel = uilabel(app.HomeworkSubmissionPanel);
            app.CanvasTokenForHomeworkLabel.Text = 'Canvas Token';
            app.CanvasTokenForHomework = uieditfield(app.HomeworkSubmissionPanel, 'text');
            app.HomeworkBrowser = uibutton(app.HomeworkSubmissionPanel, 'push');
            app.HomeworkBrowser.ButtonPushedFcn = createCallbackFcn(app, @HomeworkBrowserButtonPushed, true);
            app.HomeworkBrowser.Text = 'Browse...';
            app.CanvasBrowser = uibutton(app.HomeworkSubmissionPanel, 'push');
            app.CanvasBrowser.ButtonPushedFcn = createCallbackFcn(app, @CanvasBrowserButtonPushed, true);
            app.CanvasBrowser.Text = 'Browse...';
            app.CanvasTokenGenerator = uibutton(app.HomeworkSubmissionPanel, 'push');
            app.CanvasTokenGenerator.ButtonPushedFcn = createCallbackFcn(app, @CanvasTokenGeneratorButtonPushed, true);
            app.CanvasTokenGenerator.Text = 'Generate Your Token...';
            app.SolutionArchivePanel = uipanel(app.AutograderUIFigure);
            app.SolutionArchivePanel.Title = '2. Solution Archive';
            app.SolutionChoiceLabel = uilabel(app.SolutionArchivePanel);
            app.SolutionChoiceLabel.Text = 'I would like to...';
            app.SolutionChoice = uidropdown(app.SolutionArchivePanel);
            app.SolutionChoice.Items = {'', 'Select the Solution Archive from Google Drive', 'Browse for a local Solution Archive'};
            app.SolutionChoice.ValueChangedFcn = createCallbackFcn(app, @SolutionChoiceValueChanged, true);
            app.SolutionChoice.Value = '';
            app.SubmissionBrowser = uibutton(app.SolutionArchivePanel, 'push');
            app.SubmissionBrowser.ButtonPushedFcn = createCallbackFcn(app, @SubmissionBrowserPushed, true);
            app.SubmissionBrowser.Text = 'Browse...';
            app.DriveTokenLabel = uilabel(app.SolutionArchivePanel);
            app.DriveTokenLabel.Text = 'Drive Token';
            app.DriveToken = uieditfield(app.SolutionArchivePanel, 'text');
            app.DriveToken.ValueChangingFcn = createCallbackFcn(app, @DriveTokenValueChanging, true);
            app.DriveBrowser = uibutton(app.SolutionArchivePanel, 'push');
            app.DriveBrowser.ButtonPushedFcn = createCallbackFcn(app, @DriveBrowserButtonPushed, true);
            app.DriveBrowser.Text = 'Browse...';
            app.DriveTokenGenerator = uibutton(app.SolutionArchivePanel, 'push');
            app.DriveTokenGenerator.ButtonPushedFcn = createCallbackFcn(app, @DriveTokenGeneratorButtonPushed, true);
            app.DriveTokenGenerator.Text = 'Generate Your Token...';
            app.OutputsPanel = uipanel(app.AutograderUIFigure);
            app.OutputsPanel.Title = '3. Outputs';
            app.UploadToCanvas = uicheckbox(app.OutputsPanel);
            app.UploadToCanvas.ValueChangedFcn = createCallbackFcn(app, @UploadToCanvasValueChanged, true);
            app.UploadToCanvas.Text = 'Upload Grades to Canvas';
            app.UploadToServer = uicheckbox(app.OutputsPanel);
            app.UploadToServer.ValueChangedFcn = createCallbackFcn(app, @UploadToServerValueChanged, true);
            app.UploadToServer.Text = 'Upload Files to CS 1371 Server';
            app.StoreLocally = uicheckbox(app.OutputsPanel);
            app.StoreLocally.ValueChangedFcn = createCallbackFcn(app, @StoreLocallyValueChanged, true);
            app.StoreLocally.Text = 'Store Output Locally';
            app.StoreDebugger = uicheckbox(app.OutputsPanel);
            app.StoreDebugger.ValueChangedFcn = createCallbackFcn(app, @StoreDebuggerValueChanged, true);
            app.StoreDebugger.Text = 'Store Debugger Information';
            app.PostAnnouncement = uicheckbox(app.OutputsPanel);
            app.PostAnnouncement.ValueChangedFcn = createCallbackFcn(app, @PostAnnouncementValueChanged, true);
            app.PostAnnouncement.Text = 'Post an Announcement on Canvas';
            app.SendEmail = uicheckbox(app.OutputsPanel);
            app.SendEmail.ValueChangedFcn = createCallbackFcn(app, @SendEmailValueChanged, true);
            app.SendEmail.Text = 'Send me an Email';
            app.CanvasTokenForUploadLabel = uilabel(app.OutputsPanel);
            app.CanvasTokenForUploadLabel.Text = 'Canvas Token';
            app.CanvasTokenForUpload = uieditfield(app.OutputsPanel, 'text');
            app.ServerUsernameLabel = uilabel(app.OutputsPanel);
            app.ServerUsernameLabel.Text = 'Username';
            app.ServerUsername = uieditfield(app.OutputsPanel, 'text');
            app.ServerPasswordLabel = uilabel(app.OutputsPanel);
            app.ServerPasswordLabel.Text = 'Password';
            app.ServerPassword = uieditfield(app.OutputsPanel, 'text');
            app.EmailAddressLabel = uilabel(app.OutputsPanel);
            app.EmailAddressLabel.Text = 'Email Address';
            app.EmailAddress = uieditfield(app.OutputsPanel, 'text');
            app.CanvasTokenForAnnouncementLabel = uilabel(app.OutputsPanel);
            app.CanvasTokenForAnnouncementLabel.Text = 'Canvas Token';
            app.CanvasTokenForAnnouncement = uieditfield(app.OutputsPanel, 'text');
            app.OutputBrowser = uibutton(app.OutputsPanel, 'push');
            app.OutputBrowser.ButtonPushedFcn = createCallbackFcn(app, @OutputBrowserButtonPushed, true);
            app.OutputBrowser.Text = 'Browse...';
            app.DebuggerBrowser = uibutton(app.OutputsPanel, 'push');
            app.DebuggerBrowser.ButtonPushedFcn = createCallbackFcn(app, @DebuggerBrowserButtonPushed, true);
            app.DebuggerBrowser.Text = 'Browse...';
            app.GoButton = uibutton(app.AutograderUIFigure, 'push');
            app.GoButton.Text = 'Go!';
            app.CancelButton = uibutton(app.AutograderUIFigure, 'push');
            app.CancelButton.ButtonPushedFcn = createCallbackFcn(app, @CancelButtonPushed, true);
            app.CancelButton.Text = 'Cancel';
        end
    end

    methods (Access = public)

        % Construct app
        function app = Autograder

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.AutograderUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.AutograderUIFigure)
        end
    end
end