classdef CanvasAuthorizer < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure             matlab.ui.Figure
        Title                matlab.ui.control.Label
        CanvasToken          matlab.ui.control.EditField
        NoTokenBanner        matlab.ui.control.Label
        Directions           matlab.ui.control.Label
        Submit               matlab.ui.control.Button
        InvalidTokenWarning  matlab.ui.control.Label
        Cancel               matlab.ui.control.Button
    end


    properties (Access = private)
        baseApp Autograder;
    end

    methods (Access = private)
    
        function isValid = validateToken(~, token)
            API = 'https://gatech.instructure.com/api/v1/courses/';
            opts = weboptions;
            opts.HeaderFields = {'Authorization', ['Bearer ' token]};
            try
                webread(API, 'per_page', '2', opts);
                isValid = true;
            catch
                isValid= false;
            end
        end
        
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, base)
            app.baseApp = base;
            app.InvalidTokenWarning.Visible = false;
        end

        % Button pushed function: Submit
        function SubmitButtonPushed(app, event)
            % check the token - if it works, store in base. Otherwise, tell user
            app.InvalidTokenWarning.Visible = false;
            if ~isempty(app.CanvasToken.Value) && app.validateToken(app.CanvasToken.Value)
                app.baseApp.canvasToken = app.CanvasToken.Value;
                app.baseApp.savePreferences();
                uiresume(app.UIFigure);
            else
                app.InvalidTokenWarning.Visible = true;
            end
        end

        % Button pushed function: Cancel
        function CancelButtonPushed(app, event)
            uiresume(app.UIFigure);
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'Canvas Authorizer';
            app.Title = uilabel(app.UIFigure);
            app.Title.Text = {'To interact with Canvas, we''ll need your Canvas API token'; 'Enter it below'};
            app.CanvasToken = uieditfield(app.UIFigure, 'text');
            app.NoTokenBanner = uilabel(app.UIFigure);
            app.NoTokenBanner.Text = 'Dont'' have a Token? Follow the instructions below:';
            app.Directions = uilabel(app.UIFigure);
            app.Directions.Text = {'1. Go to your profile/account page on Canvas, then go to Settings'; '2. Go to the Approved Integrations section, and click the New Access Token button'; '3. Name it something that''s descriptive - something like "Autograder" should be sufficient'; '4. You can give it an expiration if you like, but we suggest you don''t.'; '5. Copy the token it provides, and paste it above.'};
            app.Submit = uibutton(app.UIFigure, 'push');
            app.Submit.ButtonPushedFcn = createCallbackFcn(app, @SubmitButtonPushed, true);
            app.Submit.Text = 'Submit';
            app.InvalidTokenWarning = uilabel(app.UIFigure);
            app.InvalidTokenWarning.Text = {'Hmmm... That token didn''t seem to work.'; 'Make sure that you entered the token correctly, and try again'};
            app.Cancel = uibutton(app.UIFigure, 'push');
            app.Cancel.ButtonPushedFcn = createCallbackFcn(app, @CancelButtonPushed, true);
            app.Cancel.Text = 'Cancel';
        end
    end

    methods (Access = public)

        % Construct app
        function app = CanvasAuthorizer(varargin)

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end