classdef CheatDetector < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure          matlab.ui.Figure
        Students          matlab.ui.control.ListBox
        CheatDetails      matlab.ui.container.Tree
        Summary           matlab.ui.control.Label
        ShowOnlyCheaters  matlab.ui.control.CheckBox
        Export            matlab.ui.control.Button
    end


    properties (Access = private)
        students;
        problems;
        scores;
        path;
        likelyCheaters;
        criticalStudents;
        cheaterStudents;
        cheaterScores;
        problemScores;
    end

    properties (Constant)
        THRESHOLD = .90;
    end

    methods (Access = private)
    
        function analyze(app)
            progress = uiprogressdlg(app.UIFigure, 'Title', 'Cheat Detector', 'Message', 'Summarizing Score Data', ...
                'Cancelable', 'off', 'Value', 0, 'Indeterminate', 'off', 'ShowPercentage', 'on');
            for s = numel(app.students):-1:1
                progress.Value = min([progress.Value + 1/numel(app.students), 1]);
                for p = numel(app.problems):-1:1
                    problem = app.problems(p);
                    % Don't list ABCs
                    if ~contains(problem.name, 'ABCs')
                        pScores = cellfun(@(v)(v(p)), app.scores{s});
                        cScores = pScores(pScores >= app.THRESHOLD);
                        cheaters = app.students(pScores >= app.THRESHOLD);
                        app.criticalStudents{s}{p} = cheaters(cScores == Inf);
                        app.cheaterStudents{s}{p} = cheaters;
                        app.cheaterScores{s}{p} = cScores;
                        app.problemScores{s}{p} = pScores;
                    end
                end
            end
        end
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, students, problems, scores, path)
            app.students = students;
            app.problems = problems;
            app.scores = scores;
            app.path = path;
            % scores is 1:N cell array; each entry is again 1xN cell array of score vectors
            % Basically, for each student, if ANY SCORE is Inf, raise the alarm
            mask = ~contains({app.problems.name}, 'ABCs');
            app.likelyCheaters = cellfun(@getLikely, app.scores);
            
            
            function tf = getLikely(score)
                % score is cell array of vectors
                score = cellfun(@(s)(s(mask)), score, 'uni', false);
                tf = any([score{:}] >= app.THRESHOLD);
            end
            app.Students.Items = cellfun(@(str)([str ')']), ...
                join([{app.students.name}; {app.students.id}]', ' ('), 'uni', false);
            app.Students.ItemsData = 1:numel(app.students);
            
            app.analyze;
        end

        % Value changed function: Students
        function StudentsValueChanged(app, event)
            progress = uiprogressdlg(app.UIFigure, 'Message', 'Loading Student Statistics', ...
                'Title', 'Cheat Detector', 'Cancelable', 'off', 'Value', 0, ...
                'Indeterminate', 'off', 'ShowPercentage', 'on');
            cleaner = onCleanup(@drawnow);
            closer = onCleanup(@()(close(progress)));
            value = app.Students.Value;
            student = app.students(value);
            % for each problem create node for it.
            app.CheatDetails.Children.delete;
            isCheat = false;
            for p = numel(app.problems):-1:1
                progress.Value = min([progress.Value + 1/numel(app.problems), 1]);
                problem = app.problems(p);
                % Don't list ABCs
                if ~contains(problem.name, 'ABCs')
                    % list critical
                    pNode = uitreenode(app.CheatDetails, 'Text', problem.name);
                    crits = app.criticalStudents{value}{p};
                    [~, inds] = sort({crits.name});
                    crits = crits(inds);
                    isCritical = false;
                    for s = 1:numel(crits)
                        isCheat = true;
                        isCritical = true;
                        uitreenode(pNode, 'Text', ...
                            [crits(s).name ' (' crits(s).id ') - exact match'], ...
                            'Icon', 'resources/critical.png');
                    end
                    cScores = app.cheaterScores{value}{p};
                    poss = app.cheaterStudents{value}{p}(cScores ~= Inf);
                    cScores = cScores(cScores ~= Inf);
                    [~, inds] = sort(cScores);
                    cScores = cScores(inds(end:-1:1));
                    poss = poss(inds(end:-1:1));
                    isPossible = false;
                    for s = 1:numel(poss)
                        isPossible = true;
                        isCheat = true;
                        uitreenode(pNode, 'Text', ...
                            [poss(s).name ' (' poss(s).id ') - ' sprintf('%0.2f', 100 * cScores(s)) '% match'], ...
                            'Icon', 'resources/warning.png');
                    end
                    if isCritical
                        pNode.Icon = 'resources/critical.png';
                    elseif isPossible
                        pNode.Icon = 'resources/warning.png';
                    end
                end
            end
            crits = [app.criticalStudents{value}{:}];
            if isempty(crits)
                names = {};
            else
                names = unique({crits.name});
            end
            if ~isCheat
                app.Summary.Text = 'It is unlikely that this student colluded with anyone';
                return;
            elseif numel(names) == 0
                app.Summary.Text = sprintf('It''s exceedingly likely (though not certain) that %s cheated', student.name);
                return;
            elseif numel(names) == 1
                app.Summary.Text = sprintf('It''s almost certain that %s and %s colluded', ...
                    student.name, names{1});
            else
                app.Summary.Text = sprintf('It''s almost certain %s cheated with multiple students', student.name);
            end
            uialert(app.UIFigure, sprintf('Likely cheater(s):\n%s', strjoin(names, newline)), 'Cheat Detected');
        end

        % Value changed function: ShowOnlyCheaters
        function ShowOnlyCheatersValueChanged(app, event)
            value = app.ShowOnlyCheaters.Value;
            if value
                likely = app.students(app.likelyCheaters);
                app.Students.Items = cellfun(@(str)([str ')']), ...
                    join([{likely.name}; {likely.id}]', ' ('), 'uni', false);
                app.Students.ItemsData = find(app.likelyCheaters);
            else
                app.Students.Items = cellfun(@(str)([str ')']), ...
                    join([{app.students.name}; {app.students.id}]', ' ('), 'uni', false);
                app.Students.ItemsData = 1:numel(app.students);
            end
            drawnow;
        end

        % Button pushed function: Export
        function ExportButtonPushed(app, event)
            % Exporting is creating:
            %   For each student
            %       For each problem
            %           Folder, all code that matched; additionally, CSV manifest & Html?
            % MAT file for all
            path = uigetdir;
            if ~isequal(path, 0)
                app.UIFigure.Visible = 'off';
                app.UIFigure.Visible = 'on';
                progress = uiprogressdlg(app.UIFigure, 'Title', 'Cheat Detector', ...
                    'ShowPercentage', 'on', 'Indeterminate', 'off', 'Cancelable', 'off');
                exportCheaters(app.students, app.cheaterStudents, app.cheaterScores, {app.problems.name}, path, progress);
            end
            web(['file://' path '/summary.html'], '-browser');
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)

            % delete our working directory
            [~] = rmdir(app.path, 's');
            delete(app);
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'Cheat Detector';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.Students = uilistbox(app.UIFigure);
            app.Students.Items = {};
            app.Students.ValueChangedFcn = createCallbackFcn(app, @StudentsValueChanged, true);
            app.Students.Value = {};
            app.CheatDetails = uitree(app.UIFigure);
            app.Summary = uilabel(app.UIFigure);
            app.Summary.Text = '';
            app.ShowOnlyCheaters = uicheckbox(app.UIFigure);
            app.ShowOnlyCheaters.ValueChangedFcn = createCallbackFcn(app, @ShowOnlyCheatersValueChanged, true);
            app.ShowOnlyCheaters.Text = 'Only Show Likely Cheaters';
            app.Export = uibutton(app.UIFigure, 'push');
            app.Export.ButtonPushedFcn = createCallbackFcn(app, @ExportButtonPushed, true);
            app.Export.Text = 'Export';
        end
    end

    methods (Access = public)

        % Construct app
        function app = CheatDetector(varargin)

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end