classdef GoogleDriveAuthorizer < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure           matlab.ui.Figure
        UserCodeLabel      matlab.ui.control.Label
        UserCode           matlab.ui.control.EditField
        Instructions       matlab.ui.control.Label
        WebsiteLabel       matlab.ui.control.Label
        Website            matlab.ui.control.EditField
        RefreshTokenLabel  matlab.ui.control.Label
        RefreshToken       matlab.ui.control.EditField
        TokenDescription   matlab.ui.control.Label
        Exit               matlab.ui.control.Button
    end


    properties (Access = private)
        clientId char = '995321590274-1msjncpalf2cj5vmqmudjj2pl7npjicd.apps.googleusercontent.com';
        clientSecret char = 'finkYqyQBbOC6HFbqEyeeHAn';
        deviceCodeUrl char = 'https://accounts.google.com/o/oauth2/device/code';
        scope char = 'https://www.googleapis.com/auth/drive.file';
        pollingUrl char = 'https://www.googleapis.com/oauth2/v4/token';
        grantType char = 'http://oauth.net/grant_type/device/1.0';
        verifyURL char;
        deviceCode char;
        userCode char;
        interval double;
        refreshToken char;
        baseApp Autograder;
    end

    methods (Access = private)
    
        function poll(app)
            % check verified; if so, display refresh token
            opts = weboptions;
            opts.RequestMethod = 'POST';
            isVerified = false;
            while ~isVerified
                try
                    data = webread(app.pollingUrl, 'client_id', app.clientId, 'client_secret', app.clientSecret, 'code', app.deviceCode, 'grant_type', app.grantType, opts);
                catch
                    pause(app.interval);
                    continue;
                end
                if isfield(data, 'refresh_token')
                    app.refreshToken = data.refresh_token;
                    app.RefreshToken.Value = data.refresh_token;
                    if ~isempty(app.baseApp)
                        app.baseApp.driveToken = data.refresh_token;
                        app.baseApp.DriveToken.Value = data.refresh_token;
                        app.baseApp.DriveBrowser.Enable = true;
                    end
                    isVerified = true;
                end
            end
        end
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, base)
            % Step 1: Get Device Verification Code
            app.baseApp = base;
            opts = weboptions;
            opts.RequestMethod = 'POST';
            data = webread(app.deviceCodeUrl, 'client_id', app.clientId, 'scope', app.scope, opts);
            % data contains:
            %   verification_url
            %   device_code
            %   user_code
            app.UserCode.Value = data.user_code;
            app.Website.Value = data.verification_url;
            app.deviceCode = data.device_code;
            app.interval = data.interval;
            app.poll();
        end

        % Button pushed function: Exit
        function ExitButtonPushed(app, event)
            uiresume(app.UIFigure);
            delete(app);
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.UserCodeLabel = uilabel(app.UIFigure);
            app.UserCodeLabel.Text = 'Code';
            app.UserCode = uieditfield(app.UIFigure, 'text');
            app.Instructions = uilabel(app.UIFigure);
            app.Instructions.Text = {'To set up your access to Google Drive, '; 'We need you to allow the autograder to access your Drive folder.'; 'Use the code and go to the given website to give this access'};
            app.WebsiteLabel = uilabel(app.UIFigure);
            app.WebsiteLabel.Text = 'Verification Website';
            app.Website = uieditfield(app.UIFigure, 'text');
            app.RefreshTokenLabel = uilabel(app.UIFigure);
            app.RefreshTokenLabel.Text = 'Token';
            app.RefreshToken = uieditfield(app.UIFigure, 'text');
            app.TokenDescription = uilabel(app.UIFigure);
            app.TokenDescription.Text = 'Keep this token safe - you''ll need it if you ever want to Access Google Drive again';
            app.Exit = uibutton(app.UIFigure, 'push');
            app.Exit.ButtonPushedFcn = createCallbackFcn(app, @ExitButtonPushed, true);
            app.Exit.Text = 'Exit';
        end
    end

    methods (Access = public)

        % Construct app
        function app = GoogleDriveAuthorizer(varargin)

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end