classdef Notifier < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        Title                    matlab.ui.control.Label
        EmailPanel               matlab.ui.container.Panel
        Email                    matlab.ui.control.Switch
        ToLabel                  matlab.ui.control.Label
        To                       matlab.ui.control.EditField
        SlackPanel               matlab.ui.container.Panel
        Slack                    matlab.ui.control.Switch
        ChannelsListBoxLabel     matlab.ui.control.Label
        Channels                 matlab.ui.control.ListBox
        UsersListBoxLabel        matlab.ui.control.Label
        Users                    matlab.ui.control.ListBox
        TextMessagingPanel       matlab.ui.container.Panel
        Text                     matlab.ui.control.Switch
        NumberLabel              matlab.ui.control.Label
        Number                   matlab.ui.control.EditField
        PostCanvasPanel          matlab.ui.container.Panel
        Post                     matlab.ui.control.Switch
        ConfigurePost            matlab.ui.control.Button
        SaveButton               matlab.ui.control.Button
        TestConfigurationButton  matlab.ui.control.Button
    end


    properties (Access = private)
        base Autograder % Description
        canvasMessage char % Description
        canvasTitle char
        canvasHtml char
        canvasMode double = 1;
        
        slackUsers struct;
        slackChannels;
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, baseApp)
            app.base = baseApp;
            % Email
            if ~isempty(app.base.email)
                app.Email.Value = 'On';
                app.To.Value = app.base.email;
            end
            % Slack
            % fill users AND groups. If ID matches, select
            choices = slackMessenger(app.base.slackToken);
            app.slackUsers = choices(strcmp({choices.type}, 'user'));
            app.slackChannels = choices(strcmp({choices.type}, 'channel'));
            
            userNames = {app.slackUsers.name};
            channelNames = {app.slackChannels.name};
            app.Channels.Items = channelNames;
            app.Channels.ItemsData = 1:numel(channelNames);
            app.Users.Items = userNames;
            app.Users.ItemsData = 1:numel(userNames);
            
            
            if ~isempty(app.base.slackRecipients)
                app.Slack.Value = 'On';
                % for each channel and/or user, engage
                % for each recipient, engage (select)
                
                selectedChannels = zeros(1, numel(app.base.slackRecipients));
                selectedUsers = zeros(1, numel(app.base.slackRecipients));
                for s = 1:numel(app.base.slackRecipients)
                    % find id
                    if contains(app.base.slackRecipients(s).id, {app.slackChannels.id})
                        selectedChannels(s) = find(strcmp(app.base.slackRecipients(s).id, {app.slackChannels.id}));
                    end
                    if contains(app.base.slackRecipients(s).id, {app.slackUsers.id})
                        selectedUsers(s) = find(strcmp(app.base.slackRecipients(s).id, {app.slackUsers.id}));
                    end
                end
                selectedChannels(selectedChannels == 0) = [];
                selectedUsers(selectedUsers == 0) = [];
                app.Channels.Value = selectedChannels;
                app.Users.Value = selectedUsers;
            end
            % Text
            if ~isempty(app.base.phoneNumber)
                app.Text.Value = 'On';
                app.Number.Value = app.base.phoneNumber;
            end
            % Canvas
            if ~isempty(app.base.canvasTitle)
                app.Post.Value = 'On';
            end
        end

        % Button pushed function: ConfigurePost
        function ConfigurePostButtonPushed(app, event)
            poster = CanvasPoster;
            poster.Title.Value = app.canvasTitle;
            poster.html = app.canvasHtml;
            poster.Message.Value = app.canvasMessage;
            if app.canvasMode == 1
                poster.IsMarkdown.Value = true;
            elseif app.canvasMode == 2
                poster.IsPlain.Value = true;
            else
                poster.IsHTML.Value = true;
            end
            uiwait(poster.UIFigure);
            % get stuff and delete
            if isvalid(poster)
                app.canvasTitle = poster.Title.Value;
                app.canvasMessage = poster.Message.Value;
                app.canvasHtml = poster.html;
                if poster.IsMarkdown.Value
                    app.canvasMode = 1;
                elseif poster.IsPlain.Value
                    app.canvasMode = 2;
                else
                    app.canvasMode = 3;
                end
            end
            delete(poster);
        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)
            if strcmp(app.Email.Value, 'On')
                app.base.email = app.To.Value;
            else
                app.base.email = '';
            end
            
            if strcmp(app.Slack.Value, 'On')
                % get inds
                inds = app.Channels.Value;
                if isempty(inds)
                    inds = [];
                end
                channels = app.slackChannels(inds);
                inds = app.Users.Value;
                if isempty(inds)
                    inds = [];
                end
                users = app.slackUsers(inds);
                app.base.slackRecipients = [channels users];
            else
                app.base.slackRecipients = app.slackUsers([]);
            end
            
            if strcmp(app.Text.Value, 'On')
                app.base.phoneNumber = app.Number.Value;
            else
                app.base.phoneNumber = '';
            end
            
            
            if strcmp(app.Post.Value, 'On')
                if isempty(app.base.canvasCourseId)
                    if isempty(app.base.canvasToken)
                        auth = CanvasAuthorizer(app.base);
                        uiwait(auth.UIFigure);
                        delete(auth);
                    end
                    if isempty(app.base.canvasToken)
                        app.base.canvasMessage = '';
                        app.base.canvasTitle = '';
                    else
                        try
                            p = uiprogressdlg(app.UIFigure, 'Title', 'Connecting', ...
                                'Message', 'Connecting with Canvas', 'Indeterminate', 'on');
                            browser = CanvasHomeworkSelector(app.base);
                            uiwait(browser.UIFigure);
                            delete(browser);
                            close(p);
                            % if no answer given (cancelled), then revert to 0
                            if isempty(app.base.canvasHomeworkId)
                                app.base.canvasMessage = '';
                                app.base.canvasTitle = '';
                            else
                                app.base.canvasMessage = app.canvasHtml;
                                app.base.canvasTitle = app.canvasTitle;
                            end
                        catch
                            uialert(app.UIFigure, 'Unable to contact Canvas', 'Canvas Selector');
                            app.base.canvasMessage = '';
                            app.base.canvasTitle = '';
                            close(p);
                        end
                    end
                else
                    app.base.canvasMessage = app.canvasHtml;
                    app.base.canvasTitle = app.canvasTitle;
                end
            else
                app.base.canvasMessage = '';
                app.base.canvasTitle = '';
            end
            uiresume(app.UIFigure);
            delete(app);
        end

        % Button pushed function: TestConfigurationButton
        function TestConfigurationButtonPushed(app, event)
            % for each option (except canvas), send a notification if it's turned on
            try
                if strcmp(app.Email.Value, 'On')
                    emailMessenger(app.To.Value, 'Test', 'This is just a test notification from the Autograder!', ...
                        app.base.notifierToken, app.base.googleClientId, app.base.googleClientSecret, ...
                        app.base.driveKey);
                end
            catch
                uialert(app.UIFigure, 'Email failed to send', 'Email Failure');
                return;
            end
            try
                if strcmp(app.Slack.Value, 'On')
                    inds = app.Channels.Value;
                    if isempty(inds)
                        inds = [];
                    end
                    channels = app.slackChannels(inds);
                    inds = app.Users.Value;
                    if isempty(inds)
                        inds = [];
                    end
                    users = app.slackUsers(inds);
                    app.base.slackRecipients = [channels users];
                    slackMessenger(app.base.slackToken, {app.base.slackRecipients.id}, 'This is just a test notification from the Autograder!');
                end
            catch
                uialert(app.UIFigure, 'Slack Message failed to send', 'Slack Failure');
                return;
            end
            try
                if strcmp(app.Text.Value, 'On')
                    textMessenger(app.Number.Value, 'This is just a test notification from the Autograder!', ...
                        app.base.twilioSid, app.base.twilioToken, app.base.twilioOrigin);
                end
            catch
                uialert(app.UIFigure, 'Text failed to send', 'Text Failure');
                return;
            end
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'Notifications';
            app.Title = uilabel(app.UIFigure);
            app.Title.Text = 'Notification Settings';
            app.EmailPanel = uipanel(app.UIFigure);
            app.EmailPanel.Title = 'Email';
            app.Email = uiswitch(app.EmailPanel, 'slider');
            app.ToLabel = uilabel(app.EmailPanel);
            app.ToLabel.Text = 'To:';
            app.To = uieditfield(app.EmailPanel, 'text');
            app.SlackPanel = uipanel(app.UIFigure);
            app.SlackPanel.Title = 'Slack';
            app.Slack = uiswitch(app.SlackPanel, 'slider');
            app.ChannelsListBoxLabel = uilabel(app.SlackPanel);
            app.ChannelsListBoxLabel.Text = 'Channels';
            app.Channels = uilistbox(app.SlackPanel);
            app.Channels.Items = {};
            app.Channels.Multiselect = 'on';
            app.Channels.Value = {};
            app.UsersListBoxLabel = uilabel(app.SlackPanel);
            app.UsersListBoxLabel.Text = 'Users';
            app.Users = uilistbox(app.SlackPanel);
            app.Users.Items = {};
            app.Users.Multiselect = 'on';
            app.Users.Value = {};
            app.TextMessagingPanel = uipanel(app.UIFigure);
            app.TextMessagingPanel.Title = 'Text Messaging';
            app.Text = uiswitch(app.TextMessagingPanel, 'slider');
            app.NumberLabel = uilabel(app.TextMessagingPanel);
            app.NumberLabel.Text = 'Phone Number';
            app.Number = uieditfield(app.TextMessagingPanel, 'text');
            app.PostCanvasPanel = uipanel(app.UIFigure);
            app.PostCanvasPanel.Title = 'Post to Canvas';
            app.Post = uiswitch(app.PostCanvasPanel, 'slider');
            app.ConfigurePost = uibutton(app.PostCanvasPanel, 'push');
            app.ConfigurePost.ButtonPushedFcn = createCallbackFcn(app, @ConfigurePostButtonPushed, true);
            app.ConfigurePost.Text = 'Configure...';
            app.SaveButton = uibutton(app.UIFigure, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Text = 'Save';
            app.TestConfigurationButton = uibutton(app.UIFigure, 'push');
            app.TestConfigurationButton.ButtonPushedFcn = createCallbackFcn(app, @TestConfigurationButtonPushed, true);
            app.TestConfigurationButton.Text = 'Test Configuration...';
        end
    end

    methods (Access = public)

        % Construct app
        function app = Notifier(varargin)

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end