classdef StudentSelector < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        StudentswithSubmissionsListBoxLabel  matlab.ui.control.Label
        AvailableListBox              matlab.ui.control.ListBox
        SelectedStudentsListBoxLabel  matlab.ui.control.Label
        SelectedListBox               matlab.ui.control.ListBox
        AddButton                     matlab.ui.control.Button
        RemoveButton                  matlab.ui.control.Button
        SearchLabel                   matlab.ui.control.Label
        SearchField                   matlab.ui.control.EditField
        SelectAllButton               matlab.ui.control.Button
        DeselectAllButton             matlab.ui.control.Button
        CancelButton                  matlab.ui.control.Button
        SubmitButton                  matlab.ui.control.Button
    end


    properties (Access = private)
        students
        base Autograder;
    end

    methods (Access = private)
    
        function getStudents(app)
            p = uiprogressdlg(app.UIFigure, 'Title', 'Acquiring Students', ...
                            'Message', 'Acquiring student information', 'Indeterminate', 'on');
            if ~isempty(app.base.homeworkArchivePath)
                % create a temporary folder
                sortedPath = tempname;
                mkdir(sortedPath);
                canvas2autograder(app.base.homeworkArchivePath, ...
                    app.base.homeworkGradebookPath, ...
                    sortedPath, ...
                    p);
                app.students = generateStudents(sortedPath, p);
                app.students = struct('name', {app.students.name}, 'login_id', {app.students.id});
                [~] = rmdir(sortedPath, 's');
            elseif ~isempty(app.base.canvasCourseId) && ~isempty(app.base.canvasHomeworkId) && ~isempty(app.base.canvasToken)
                app.students = getCanvasStudents(app.base.canvasCourseId, app.base.canvasHomeworkId, ...
                    app.base.canvasToken, p);
            else
                delete(app);
                return;
            end
            app.AvailableListBox.ItemsData = app.students;
            for i = 1:length(app.students)
                student = app.students(i);
                app.AvailableListBox.Items = [app.AvailableListBox.Items {[student.name ' (' student.login_id ')']}];
            end
            close(p);
        end
        
    end


    methods (Access = private)

        % Code that executes after component creation
        function onStartup(app, mainapp)
            app.base = mainapp;
            addpath(genpath(fileparts(fileparts(mfilename('fullpath')))));
            getStudents(app);
        end

        % Button pushed function: SelectAllButton
        function SelectAllPressed(app, event)
            app.AvailableListBox.Value = app.AvailableListBox.ItemsData;
        end

        % Button pushed function: DeselectAllButton
        function DeselectAllPressed(app, event)
            app.AvailableListBox.Value = {};
        end

        % Button pushed function: AddButton
        function AddButtonPressed(app, event)
            app.SelectedListBox.ItemsData = [app.SelectedListBox.ItemsData ...
                    app.AvailableListBox.Value];
            for i = 1:length(app.AvailableListBox.Value)
                student = app.AvailableListBox.Value(i);
                app.SelectedListBox.Items = [app.SelectedListBox.Items {[student.name ' (' student.login_id ')']}];
            end
        end

        % Button pushed function: RemoveButton
        function RemoveButtonPressed(app, event)
            for item = app.SelectedListBox.Value
                ind = strcmp({app.SelectedListBox.ItemsData.login_id}, item.login_id);
                app.SelectedListBox.ItemsData(ind) = [];
                app.SelectedListBox.Items(ind) = [];
            end
        end

        % Button pushed function: CancelButton
        function CancelButtonPressed(app, event)
            
            delete(app);
        end

        % Value changing function: SearchField
        function SearchFieldChanged(app, event)
            app.AvailableListBox.BackgroundColor = [0.9 0.9 0.9];
            drawnow;
            changingValue = event.Value;
            app.AvailableListBox.ItemsData = [];
            app.AvailableListBox.Items = {};
            for i = 1:length(app.students)
                student = app.students(i);
                if contains(student.name, changingValue, 'IgnoreCase', true) || contains(student.login_id, changingValue, 'IgnoreCase', true)
                    app.AvailableListBox.Items = [app.AvailableListBox.Items {[student.name ' (' student.login_id ')']}];
                    app.AvailableListBox.ItemsData = [app.AvailableListBox.ItemsData student];
                end
            end
            app.AvailableListBox.BackgroundColor = [1 1 1];
        end

        % Button pushed function: SubmitButton
        function SubmitButtonPressed(app, event)
            app.base.selectedStudents = app.SelectedListBox.ItemsData;
            delete(app);
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'Student Selector';
            app.StudentswithSubmissionsListBoxLabel = uilabel(app.UIFigure);
            app.StudentswithSubmissionsListBoxLabel.Text = 'Students with Submissions';
            app.AvailableListBox = uilistbox(app.UIFigure);
            app.AvailableListBox.Items = {};
            app.AvailableListBox.Multiselect = 'on';
            app.AvailableListBox.Value = {};
            app.SelectedStudentsListBoxLabel = uilabel(app.UIFigure);
            app.SelectedStudentsListBoxLabel.Text = 'Selected Students';
            app.SelectedListBox = uilistbox(app.UIFigure);
            app.SelectedListBox.Items = {};
            app.SelectedListBox.Multiselect = 'on';
            app.SelectedListBox.Value = {};
            app.AddButton = uibutton(app.UIFigure, 'push');
            app.AddButton.ButtonPushedFcn = createCallbackFcn(app, @AddButtonPressed, true);
            app.AddButton.Text = '>>';
            app.RemoveButton = uibutton(app.UIFigure, 'push');
            app.RemoveButton.ButtonPushedFcn = createCallbackFcn(app, @RemoveButtonPressed, true);
            app.RemoveButton.Text = '<<';
            app.SearchLabel = uilabel(app.UIFigure);
            app.SearchLabel.Text = 'Search';
            app.SearchField = uieditfield(app.UIFigure, 'text');
            app.SearchField.ValueChangingFcn = createCallbackFcn(app, @SearchFieldChanged, true);
            app.SelectAllButton = uibutton(app.UIFigure, 'push');
            app.SelectAllButton.ButtonPushedFcn = createCallbackFcn(app, @SelectAllPressed, true);
            app.SelectAllButton.Text = 'Select All';
            app.DeselectAllButton = uibutton(app.UIFigure, 'push');
            app.DeselectAllButton.ButtonPushedFcn = createCallbackFcn(app, @DeselectAllPressed, true);
            app.DeselectAllButton.Text = 'Deselect All';
            app.CancelButton = uibutton(app.UIFigure, 'push');
            app.CancelButton.ButtonPushedFcn = createCallbackFcn(app, @CancelButtonPressed, true);
            app.CancelButton.Text = 'Cancel';
            app.SubmitButton = uibutton(app.UIFigure, 'push');
            app.SubmitButton.ButtonPushedFcn = createCallbackFcn(app, @SubmitButtonPressed, true);
            app.SubmitButton.Text = 'Submit';
        end
    end

    methods (Access = public)

        % Construct app
        function app = StudentSelector(varargin)

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)onStartup(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end