classdef autograder < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        AutograderUIFigure           matlab.ui.Figure
        FileMenu                     matlab.ui.container.Menu
        EditMenu                     matlab.ui.container.Menu
        AcknowledgementsMenu         matlab.ui.container.Menu
        HomeworkSubmissionPanel      matlab.ui.container.Panel
        IsResubmission               matlab.ui.control.CheckBox
        HomeworkNumberLabel          matlab.ui.control.Label
        HomeworkNumber               matlab.ui.control.NumericEditField
        HomeworkChoiceLabel          matlab.ui.control.Label
        HomeworkChoice               matlab.ui.control.DropDown
        CanvasTokenForHomeworkLabel  matlab.ui.control.Label
        CanvasTokenForHomework       matlab.ui.control.EditField
        HomeworkBrowser              matlab.ui.control.Button
        SolutionArchivePanel         matlab.ui.container.Panel
        SolutionChoiceLabel          matlab.ui.control.Label
        SolutionChoice               matlab.ui.control.DropDown
        OutputsPanel                 matlab.ui.container.Panel
        UploadToCanvas               matlab.ui.control.CheckBox
        UploadToServer               matlab.ui.control.CheckBox
        StoreLocally                 matlab.ui.control.CheckBox
        StoreDebugger                matlab.ui.control.CheckBox
        PostAnnouncement             matlab.ui.control.CheckBox
        SendEmail                    matlab.ui.control.CheckBox
        CanvasTokenForUploadLabel    matlab.ui.control.Label
        CanvasTokenForUpload         matlab.ui.control.EditField
        GoogleUsernameLabel          matlab.ui.control.Label
        GoogleUsername               matlab.ui.control.EditField
        GooglePasswordLabel          matlab.ui.control.Label
        GooglePassword               matlab.ui.control.EditField
        EmailAddressLabel            matlab.ui.control.Label
        EmailAddress                 matlab.ui.control.EditField
        CanvasTokenForAnnouncementLabel  matlab.ui.control.Label
        CanvasTokenForAnnouncement   matlab.ui.control.EditField
    end


    properties (Access = public)
        canvasToken char
        googleUsername char
        googlePassword char
        submissionArchive char
        solutionArchive char
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % hide optionals
            app.CanvasTokenForHomework.Visible = false;
            app.CanvasTokenForHomeworkLabel.Visible = false;
        end

        % Value changed function: HomeworkChoice
        function HomeworkChoiceSelected(app, event)
            persistent previousChoice;
            value = app.HomeworkChoice.Value;
            if strcmp(value, 'Select the submission from Canvas')
                app.CanvasTokenForHomework.Visible = true;
                app.CanvasTokenForHomeworkLabel.Visible = true;
                % move the number, isResub down by height of this
                app.IsResubmission.Position = app.IsResubmission.Position - ...
                    [0 app.CanvasTokenForHomework.Position(4) 0 0];
                app.HomeworkNumber.Position = app.HomeworkNumber.Position - ...
                    [0 app.CanvasTokenForHomework.Position(4) 0 0];
                app.HomeworkNumberLabel.Position = app.HomeworkNumberLabel.Position - ...
                    [0 app.CanvasTokenForHomework.Position(4) 0 0];
            else
                % get zip file
                [name, path] = uigetfile({'*.m', 'Zipped Archives (*.zip'}, 'Pick the submission archive');
                if isequal(name, 0)
                    % cancelled; revert to previous choice
                end
                % check if first; we don't move up if it is
                if ~isempty(isFirst)
                    % hide it     
                    app.CanvasTokenForHomework.Visible = false;
                    app.CanvasTokenForHomeworkLabel.Visible = false;
                    % move back up
                    app.IsResubmission.Position = app.IsResubmission.Position + ...
                        [0 app.CanvasTokenForHomework.Position(4) 0 0];
                    app.HomeworkNumber.Position = app.HomeworkNumber.Position + ...
                        [0 app.CanvasTokenForHomework.Position(4) 0 0];
                    app.HomeworkNumberLabel.Position = app.HomeworkNumberLabel.Position + ...
                        [0 app.CanvasTokenForHomework.Position(4) 0 0];
                end
            end
            isFirst = true;
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.AutograderUIFigure = uifigure;
            app.AutograderUIFigure.Name = 'Autograder';
            app.FileMenu = uimenu(app.AutograderUIFigure);
            app.FileMenu.Text = 'File';
            app.EditMenu = uimenu(app.AutograderUIFigure);
            app.EditMenu.Text = 'Edit';
            app.AcknowledgementsMenu = uimenu(app.AutograderUIFigure);
            app.AcknowledgementsMenu.Text = 'Acknowledgements';
            app.HomeworkSubmissionPanel = uipanel(app.AutograderUIFigure);
            app.HomeworkSubmissionPanel.Title = '1. Homework Submission';
            app.IsResubmission = uicheckbox(app.HomeworkSubmissionPanel);
            app.IsResubmission.Text = 'Resubmission';
            app.HomeworkNumberLabel = uilabel(app.HomeworkSubmissionPanel);
            app.HomeworkNumberLabel.Text = 'Homework Number';
            app.HomeworkNumber = uieditfield(app.HomeworkSubmissionPanel, 'numeric');
            app.HomeworkChoiceLabel = uilabel(app.HomeworkSubmissionPanel);
            app.HomeworkChoiceLabel.Text = 'I would like to...';
            app.HomeworkChoice = uidropdown(app.HomeworkSubmissionPanel);
            app.HomeworkChoice.Items = {'', 'Select the submission from Canvas', 'Browse locally for a submission archive'};
            app.HomeworkChoice.ValueChangedFcn = createCallbackFcn(app, @HomeworkChoiceSelected, true);
            app.HomeworkChoice.Value = '';
            app.CanvasTokenForHomeworkLabel = uilabel(app.HomeworkSubmissionPanel);
            app.CanvasTokenForHomeworkLabel.Text = 'Canvas Token';
            app.CanvasTokenForHomework = uieditfield(app.HomeworkSubmissionPanel, 'text');
            app.HomeworkBrowser = uibutton(app.HomeworkSubmissionPanel, 'push');
            app.HomeworkBrowser.Text = 'Browse...';
            app.SolutionArchivePanel = uipanel(app.AutograderUIFigure);
            app.SolutionArchivePanel.Title = '2. Solution Archive';
            app.SolutionChoiceLabel = uilabel(app.SolutionArchivePanel);
            app.SolutionChoiceLabel.Text = 'I would like to...';
            app.SolutionChoice = uidropdown(app.SolutionArchivePanel);
            app.SolutionChoice.Items = {'', 'Select the Solution Archive from Google Drive', 'Browse for a local Solution Archive'};
            app.SolutionChoice.Value = '';
            app.OutputsPanel = uipanel(app.AutograderUIFigure);
            app.OutputsPanel.Title = '3. Outputs';
            app.UploadToCanvas = uicheckbox(app.OutputsPanel);
            app.UploadToCanvas.Text = 'Upload Grades to Canvas';
            app.UploadToServer = uicheckbox(app.OutputsPanel);
            app.UploadToServer.Text = 'Upload Files to CS 1371 Server';
            app.StoreLocally = uicheckbox(app.OutputsPanel);
            app.StoreLocally.Text = 'Store Output Locally';
            app.StoreDebugger = uicheckbox(app.OutputsPanel);
            app.StoreDebugger.Text = 'Store Debugger Information';
            app.PostAnnouncement = uicheckbox(app.OutputsPanel);
            app.PostAnnouncement.Text = 'Post an Announcement on Canvas';
            app.SendEmail = uicheckbox(app.OutputsPanel);
            app.SendEmail.Text = 'Send me an Email';
            app.CanvasTokenForUploadLabel = uilabel(app.OutputsPanel);
            app.CanvasTokenForUploadLabel.Text = 'Canvas Token';
            app.CanvasTokenForUpload = uieditfield(app.OutputsPanel, 'text');
            app.GoogleUsernameLabel = uilabel(app.OutputsPanel);
            app.GoogleUsernameLabel.Text = 'Username';
            app.GoogleUsername = uieditfield(app.OutputsPanel, 'text');
            app.GooglePasswordLabel = uilabel(app.OutputsPanel);
            app.GooglePasswordLabel.Text = 'Password';
            app.GooglePassword = uieditfield(app.OutputsPanel, 'text');
            app.EmailAddressLabel = uilabel(app.OutputsPanel);
            app.EmailAddressLabel.Text = 'Email Address';
            app.EmailAddress = uieditfield(app.OutputsPanel, 'text');
            app.CanvasTokenForAnnouncementLabel = uilabel(app.OutputsPanel);
            app.CanvasTokenForAnnouncementLabel.Text = 'Canvas Token';
            app.CanvasTokenForAnnouncement = uieditfield(app.OutputsPanel, 'text');
        end
    end

    methods (Access = public)

        % Construct app
        function app = autograder

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.AutograderUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.AutograderUIFigure)
        end
    end
end